classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        OperationButtonPanel   matlab.ui.container.Panel
        StopButton             matlab.ui.control.Button
        RunButton              matlab.ui.control.Button
        HomeButton             matlab.ui.control.Button
        RestPoseButton         matlab.ui.control.Button
        ForwardKinematicPanel  matlab.ui.container.Panel
        Joint2SliderLabel      matlab.ui.control.Label
        Joint2Slider           matlab.ui.control.Slider
        Joint3SliderLabel      matlab.ui.control.Label
        Joint3Slider           matlab.ui.control.Slider
        Joint4SliderLabel      matlab.ui.control.Label
        Joint4Slider           matlab.ui.control.Slider
        Joint5SliderLabel      matlab.ui.control.Label
        Joint5Slider           matlab.ui.control.Slider
        Joint6SliderLabel      matlab.ui.control.Label
        Joint6Slider           matlab.ui.control.Slider
        Joint1SliderLabel      matlab.ui.control.Label
        Joint1Slider           matlab.ui.control.Slider
        Label_1                matlab.ui.control.Label
        Label_2                matlab.ui.control.Label
        Label_3                matlab.ui.control.Label
        Label_4                matlab.ui.control.Label
        Label_5                matlab.ui.control.Label
        Label_6                matlab.ui.control.Label
        InverseKinematicPanel  matlab.ui.container.Panel
        PXEditFieldLabel       matlab.ui.control.Label
        PXEditField            matlab.ui.control.NumericEditField
        PYEditFieldLabel       matlab.ui.control.Label
        PYEditField            matlab.ui.control.NumericEditField
        PZEditFieldLabel       matlab.ui.control.Label
        PZEditField            matlab.ui.control.NumericEditField
        ComputeButton          matlab.ui.control.Button
        CurrentPosePanel       matlab.ui.container.Panel
        Label_7                matlab.ui.control.Label
        Label_8                matlab.ui.control.Label
        Label_9                matlab.ui.control.Label
        PXLabel                matlab.ui.control.Label
        PYLabel                matlab.ui.control.Label
        PZLabel                matlab.ui.control.Label
        ContextMenu            matlab.ui.container.ContextMenu
        Menu2                  matlab.ui.container.Menu
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
set_param('UR3','SimulationCommand','start')
L(1)=Link([0 151.90 0 pi/2]);
L(2)=Link([0 0 243.65 0]);
L(3)=Link([0 119.85 213.25 0]);
L(4)=Link([0 -93 0 -pi/2]);
L(5)=Link([0 83.25 0 pi/2]);
L(6)=Link([0 83 0 0]);

L(1).offset=pi/2;
L(2).offset=pi/2;
L(4).offset=-pi/2;

arm=SerialLink(L);
arm.name='ur3_r';
q_z = [0 0 0 0 0 0];
q_rest = [0 0 -pi/2 0 pi/4 0];

app.Label_1.Text=string(app.Joint1Slider.Value);
app.Label_2.Text=string(app.Joint2Slider.Value);
app.Label_3.Text=string(app.Joint3Slider.Value);
app.Label_4.Text=string(app.Joint4Slider.Value);
app.Label_5.Text=string(app.Joint5Slider.Value);
app.Label_6.Text=string(app.Joint6Slider.Value);
set_param('UR3/Slider Gain', 'Gain', num2str(app.Joint1Slider.Value));
set_param('UR3/Slider Gain1', 'Gain', num2str(app.Joint2Slider.Value));
set_param('UR3/Slider Gain2', 'Gain', num2str(app.Joint3Slider.Value));
set_param('UR3/Slider Gain3', 'Gain', num2str(app.Joint4Slider.Value));
set_param('UR3/Slider Gain4', 'Gain', num2str(app.Joint5Slider.Value));
set_param('UR3/Slider Gain5', 'Gain', num2str(app.Joint6Slider.Value));

T_init=arm.fkine(q_z);

        end

        % Value changed function: Joint1Slider
        function Joint1SliderValueChanged(app, event)
            value = app.Joint1Slider.Value;
            app.Label_1.Text=string(value);
            set_param('UR3/Slider Gain', 'Gain', num2str(value));

L(1)=Link([0 151.90 0 pi/2]);
L(2)=Link([0 0 243.65 0]);
L(3)=Link([0 119.85 213.25 0]);
L(4)=Link([0 -93 0 -pi/2]);
L(5)=Link([0 83.25 0 pi/2]);
L(6)=Link([0 83 0 0]);

L(1).offset=pi/2;
L(2).offset=pi/2;
L(4).offset=-pi/2;

arm=SerialLink(L);
q_cur=[app.Joint1Slider.Value app.Joint2Slider.Value app.Joint3Slider.Value app.Joint4Slider.Value app.Joint5Slider.Value app.Joint6Slider.Value];
q_cur=q_cur*pi/180;
T=arm.fkine(q_cur);
trs=transl(T);
app.Label_7.Text=string(trs(1));
app.Label_8.Text=string(trs(2));
app.Label_9.Text=string(trs(3));
        end

        % Value changed function: Joint2Slider
        function Joint2SliderValueChanged(app, event)
            value = app.Joint2Slider.Value;
            app.Label_2.Text=string(value);
            set_param('UR3/Slider Gain1', 'Gain', num2str(value));
L(1)=Link([0 151.90 0 pi/2]);
L(2)=Link([0 0 243.65 0]);
L(3)=Link([0 119.85 213.25 0]);
L(4)=Link([0 -93 0 -pi/2]);
L(5)=Link([0 83.25 0 pi/2]);
L(6)=Link([0 83 0 0]);

L(1).offset=pi/2;
L(2).offset=pi/2;
L(4).offset=-pi/2;

arm=SerialLink(L);
q_cur=[app.Joint1Slider.Value app.Joint2Slider.Value app.Joint3Slider.Value app.Joint4Slider.Value app.Joint5Slider.Value app.Joint6Slider.Value];
q_cur=q_cur*pi/180;
T=arm.fkine(q_cur);
trs=transl(T);
app.Label_7.Text=string(trs(1));
app.Label_8.Text=string(trs(2));
app.Label_9.Text=string(trs(3));
        end

        % Value changed function: Joint3Slider
        function Joint3SliderValueChanged(app, event)
            value = app.Joint3Slider.Value;
            app.Label_3.Text=string(value);
            set_param('UR3/Slider Gain2', 'Gain', num2str(value));
L(1)=Link([0 151.90 0 pi/2]);
L(2)=Link([0 0 243.65 0]);
L(3)=Link([0 119.85 213.25 0]);
L(4)=Link([0 -93 0 -pi/2]);
L(5)=Link([0 83.25 0 pi/2]);
L(6)=Link([0 83 0 0]);

L(1).offset=pi/2;
L(2).offset=pi/2;
L(4).offset=-pi/2;

arm=SerialLink(L);
q_cur=[app.Joint1Slider.Value app.Joint2Slider.Value app.Joint3Slider.Value app.Joint4Slider.Value app.Joint5Slider.Value app.Joint6Slider.Value];
q_cur=q_cur*pi/180;
T=arm.fkine(q_cur);
trs=transl(T);
app.Label_7.Text=string(trs(1));
app.Label_8.Text=string(trs(2));
app.Label_9.Text=string(trs(3));            
        end

        % Value changed function: Joint4Slider
        function Joint4SliderValueChanged(app, event)
            value = app.Joint4Slider.Value;
            app.Label_4.Text=string(value);
            set_param('UR3/Slider Gain3', 'Gain', num2str(value));
L(1)=Link([0 151.90 0 pi/2]);
L(2)=Link([0 0 243.65 0]);
L(3)=Link([0 119.85 213.25 0]);
L(4)=Link([0 -93 0 -pi/2]);
L(5)=Link([0 83.25 0 pi/2]);
L(6)=Link([0 83 0 0]);

L(1).offset=pi/2;
L(2).offset=pi/2;
L(4).offset=-pi/2;

arm=SerialLink(L);
q_cur=[app.Joint1Slider.Value app.Joint2Slider.Value app.Joint3Slider.Value app.Joint4Slider.Value app.Joint5Slider.Value app.Joint6Slider.Value];
q_cur=q_cur*pi/180;
T=arm.fkine(q_cur);
trs=transl(T);
app.Label_7.Text=string(trs(1));
app.Label_8.Text=string(trs(2));
app.Label_9.Text=string(trs(3));           
        end

        % Value changed function: Joint5Slider
        function Joint5SliderValueChanged(app, event)
            value = app.Joint5Slider.Value;
            app.Label_5.Text=string(value);
            set_param('UR3/Slider Gain4', 'Gain', num2str(value));
L(1)=Link([0 151.90 0 pi/2]);
L(2)=Link([0 0 243.65 0]);
L(3)=Link([0 119.85 213.25 0]);
L(4)=Link([0 -93 0 -pi/2]);
L(5)=Link([0 83.25 0 pi/2]);
L(6)=Link([0 83 0 0]);

L(1).offset=pi/2;
L(2).offset=pi/2;
L(4).offset=-pi/2;

arm=SerialLink(L);
q_cur=[app.Joint1Slider.Value app.Joint2Slider.Value app.Joint3Slider.Value app.Joint4Slider.Value app.Joint5Slider.Value app.Joint6Slider.Value];
q_cur=q_cur*pi/180;
T=arm.fkine(q_cur);
trs=transl(T);
app.Label_7.Text=string(trs(1));
app.Label_8.Text=string(trs(2));
app.Label_9.Text=string(trs(3));            
        end

        % Value changed function: Joint6Slider
        function Joint6SliderValueChanged(app, event)
            value = app.Joint6Slider.Value;
            app.Label_6.Text=string(value);
            set_param('UR3/Slider Gain5', 'Gain', num2str(value));
L(1)=Link([0 151.90 0 pi/2]);
L(2)=Link([0 0 243.65 0]);
L(3)=Link([0 119.85 213.25 0]);
L(4)=Link([0 -93 0 -pi/2]);
L(5)=Link([0 83.25 0 pi/2]);
L(6)=Link([0 83 0 0]);

L(1).offset=pi/2;
L(2).offset=pi/2;
L(4).offset=-pi/2;

arm=SerialLink(L);
q_cur=[app.Joint1Slider.Value app.Joint2Slider.Value app.Joint3Slider.Value app.Joint4Slider.Value app.Joint5Slider.Value app.Joint6Slider.Value];
q_cur=q_cur*pi/180;
T=arm.fkine(q_cur)
app.Label_7.Text=string(trs(1));
app.Label_8.Text=string(trs(2));
app.Label_9.Text=string(trs(3));            
        end

        % Button pushed function: RestPoseButton
        function RestPoseButtonPushed(app, event)
            app.Joint1Slider.Value=0;
            app.Joint2Slider.Value=0;
            app.Joint3Slider.Value=-90;
            app.Joint4Slider.Value=0;
            app.Joint5Slider.Value=45;            
            app.Joint6Slider.Value=0;
            app.Label_1.Text='0'
            app.Label_2.Text='0'
            app.Label_3.Text='-90'
            app.Label_4.Text='0'
            app.Label_5.Text='45'
            app.Label_6.Text='0'
            set_param('UR3/Slider Gain', 'Gain', num2str(app.Joint1Slider.Value));
            set_param('UR3/Slider Gain1', 'Gain', num2str(app.Joint2Slider.Value));
            set_param('UR3/Slider Gain2', 'Gain', num2str(app.Joint3Slider.Value));
            set_param('UR3/Slider Gain3', 'Gain', num2str(app.Joint4Slider.Value));
            set_param('UR3/Slider Gain4', 'Gain', num2str(app.Joint5Slider.Value));
            set_param('UR3/Slider Gain5', 'Gain', num2str(app.Joint6Slider.Value));

L(1)=Link([0 151.90 0 pi/2]);
L(2)=Link([0 0 243.65 0]);
L(3)=Link([0 119.85 213.25 0]);
L(4)=Link([0 -93 0 -pi/2]);
L(5)=Link([0 83.25 0 pi/2]);
L(6)=Link([0 83 0 0]);

L(1).offset=pi/2;
L(2).offset=pi/2;
L(4).offset=-pi/2;

arm=SerialLink(L);
q_cur=[app.Joint1Slider.Value app.Joint2Slider.Value app.Joint3Slider.Value app.Joint4Slider.Value app.Joint5Slider.Value app.Joint6Slider.Value];
q_cur=q_cur*pi/180;
T=arm.fkine(q_cur)
trs=transl(T);
app.Label_7.Text=string(trs(1));
app.Label_8.Text=string(trs(2));
app.Label_9.Text=string(trs(3));
        end

        % Button pushed function: HomeButton
        function HomeButtonPushed(app, event)
            app.Joint1Slider.Value=0;
            app.Joint2Slider.Value=0;
            app.Joint3Slider.Value=0;
            app.Joint4Slider.Value=0;
            app.Joint5Slider.Value=0;            
            app.Joint6Slider.Value=0;
            app.Label_1.Text='0'
            app.Label_2.Text='0'
            app.Label_3.Text='0'
            app.Label_4.Text='0'
            app.Label_5.Text='0'
            app.Label_6.Text='0'
            set_param('UR3/Slider Gain', 'Gain', num2str(app.Joint1Slider.Value));
            set_param('UR3/Slider Gain1', 'Gain', num2str(app.Joint2Slider.Value));
            set_param('UR3/Slider Gain2', 'Gain', num2str(app.Joint3Slider.Value));
            set_param('UR3/Slider Gain3', 'Gain', num2str(app.Joint4Slider.Value));
            set_param('UR3/Slider Gain4', 'Gain', num2str(app.Joint5Slider.Value));
            set_param('UR3/Slider Gain5', 'Gain', num2str(app.Joint6Slider.Value));

L(1)=Link([0 151.90 0 pi/2]);
L(2)=Link([0 0 243.65 0]);
L(3)=Link([0 119.85 213.25 0]);
L(4)=Link([0 -93 0 -pi/2]);
L(5)=Link([0 83.25 0 pi/2]);
L(6)=Link([0 83 0 0]);

L(1).offset=pi/2;
L(2).offset=pi/2;
L(4).offset=-pi/2;

arm=SerialLink(L);
T=arm.fkine([app.Joint1Slider.Value app.Joint2Slider.Value app.Joint3Slider.Value app.Joint4Slider.Value app.Joint5Slider.Value app.Joint6Slider.Value])
trs=transl(T);
app.Label_7.Text=string(trs(1));
app.Label_8.Text=string(trs(2));
app.Label_9.Text=string(trs(3));
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            set_param('UR3','SimulationCommand','stop')
        end

        % Button pushed function: ComputeButton
        function ComputeButtonPushed(app, event)
L(1)=Link([0 151.90 0 pi/2]);
L(2)=Link([0 0 243.65 0]);
L(3)=Link([0 119.85 213.25 0]);
L(4)=Link([0 -93 0 -pi/2]);
L(5)=Link([0 83.25 0 pi/2]);
L(6)=Link([0 83 0 0]);

L(1).offset=pi/2;
L(2).offset=pi/2;
L(4).offset=-pi/2;

arm=SerialLink(L);

PX=app.PXEditField.Value
PY=app.PYEditField.Value
PZ=app.PZEditField.Value

T_ik=[1 0 0 PX;
      0 1 0 PY;
      0 0 1 PZ;
      0 0 0 1];
T_ik=SE3.convert(T_ik);
q_current= [app.Joint1Slider.Value app.Joint2Slider.Value app.Joint3Slider.Value app.Joint4Slider.Value app.Joint5Slider.Value app.Joint6Slider.Value];
q_current= q_current*pi/180;
q=arm.ikine(T_ik,q_current,'mask',[1 1 1 0 0 0]);

app.Joint1Slider.Value=q(1)*180/pi;
app.Joint2Slider.Value=q(2)*180/pi;
app.Joint3Slider.Value=q(3)*180/pi;
app.Joint4Slider.Value=q(4)*180/pi;
app.Joint5Slider.Value=q(5)*180/pi;            
app.Joint6Slider.Value=q(6)*180/pi;

set_param('UR3/Slider Gain', 'Gain', num2str(app.Joint1Slider.Value));
set_param('UR3/Slider Gain1', 'Gain', num2str(app.Joint2Slider.Value));
set_param('UR3/Slider Gain2', 'Gain', num2str(app.Joint3Slider.Value));
set_param('UR3/Slider Gain3', 'Gain', num2str(app.Joint4Slider.Value));
set_param('UR3/Slider Gain4', 'Gain', num2str(app.Joint5Slider.Value));
set_param('UR3/Slider Gain5', 'Gain', num2str(app.Joint6Slider.Value));

q_cur=[app.Joint1Slider.Value app.Joint2Slider.Value app.Joint3Slider.Value app.Joint4Slider.Value app.Joint5Slider.Value app.Joint6Slider.Value];
q_cur=q_cur*pi/180;
T=arm.fkine(q_cur);
T=arm.fkine(q_cur);
trs=transl(T);
app.Label_7.Text=string(trs(1));
app.Label_8.Text=string(trs(2));
app.Label_9.Text=string(trs(3));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OperationButtonPanel = uipanel(app.UIFigure);
            app.OperationButtonPanel.Title = 'Operation Button';
            app.StopButton = uibutton(app.OperationButtonPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.RunButton = uibutton(app.OperationButtonPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.HomeButton = uibutton(app.OperationButtonPanel, 'push');
            app.HomeButton.ButtonPushedFcn = createCallbackFcn(app, @HomeButtonPushed, true);
            app.HomeButton.Text = 'Home';
            app.RestPoseButton = uibutton(app.OperationButtonPanel, 'push');
            app.RestPoseButton.ButtonPushedFcn = createCallbackFcn(app, @RestPoseButtonPushed, true);
            app.RestPoseButton.Text = 'Rest Pose';
            app.ForwardKinematicPanel = uipanel(app.UIFigure);
            app.ForwardKinematicPanel.Title = 'Forward Kinematic';
            app.Joint2SliderLabel = uilabel(app.ForwardKinematicPanel);
            app.Joint2SliderLabel.Text = 'Joint 2';
            app.Joint2Slider = uislider(app.ForwardKinematicPanel);
            app.Joint2Slider.Limits = [-180 180];
            app.Joint2Slider.ValueChangedFcn = createCallbackFcn(app, @Joint2SliderValueChanged, true);
            app.Joint3SliderLabel = uilabel(app.ForwardKinematicPanel);
            app.Joint3SliderLabel.Text = 'Joint 3';
            app.Joint3Slider = uislider(app.ForwardKinematicPanel);
            app.Joint3Slider.Limits = [-180 180];
            app.Joint3Slider.ValueChangedFcn = createCallbackFcn(app, @Joint3SliderValueChanged, true);
            app.Joint4SliderLabel = uilabel(app.ForwardKinematicPanel);
            app.Joint4SliderLabel.Text = 'Joint 4';
            app.Joint4Slider = uislider(app.ForwardKinematicPanel);
            app.Joint4Slider.Limits = [-180 180];
            app.Joint4Slider.ValueChangedFcn = createCallbackFcn(app, @Joint4SliderValueChanged, true);
            app.Joint5SliderLabel = uilabel(app.ForwardKinematicPanel);
            app.Joint5SliderLabel.Text = 'Joint 5';
            app.Joint5Slider = uislider(app.ForwardKinematicPanel);
            app.Joint5Slider.Limits = [-180 180];
            app.Joint5Slider.ValueChangedFcn = createCallbackFcn(app, @Joint5SliderValueChanged, true);
            app.Joint6SliderLabel = uilabel(app.ForwardKinematicPanel);
            app.Joint6SliderLabel.Text = 'Joint 6';
            app.Joint6Slider = uislider(app.ForwardKinematicPanel);
            app.Joint6Slider.Limits = [-180 180];
            app.Joint6Slider.ValueChangedFcn = createCallbackFcn(app, @Joint6SliderValueChanged, true);
            app.Joint1SliderLabel = uilabel(app.ForwardKinematicPanel);
            app.Joint1SliderLabel.Text = 'Joint 1';
            app.Joint1Slider = uislider(app.ForwardKinematicPanel);
            app.Joint1Slider.Limits = [-180 180];
            app.Joint1Slider.ValueChangedFcn = createCallbackFcn(app, @Joint1SliderValueChanged, true);
            app.Label_1 = uilabel(app.ForwardKinematicPanel);
            app.Label_1.Text = '';
            app.Label_2 = uilabel(app.ForwardKinematicPanel);
            app.Label_2.Text = '';
            app.Label_3 = uilabel(app.ForwardKinematicPanel);
            app.Label_3.Text = '';
            app.Label_4 = uilabel(app.ForwardKinematicPanel);
            app.Label_4.Text = '';
            app.Label_5 = uilabel(app.ForwardKinematicPanel);
            app.Label_5.Text = '';
            app.Label_6 = uilabel(app.ForwardKinematicPanel);
            app.Label_6.Text = '';
            app.InverseKinematicPanel = uipanel(app.UIFigure);
            app.InverseKinematicPanel.Title = 'Inverse Kinematic';
            app.PXEditFieldLabel = uilabel(app.InverseKinematicPanel);
            app.PXEditFieldLabel.Text = 'PX';
            app.PXEditField = uieditfield(app.InverseKinematicPanel, 'numeric');
            app.PYEditFieldLabel = uilabel(app.InverseKinematicPanel);
            app.PYEditFieldLabel.Text = 'PY';
            app.PYEditField = uieditfield(app.InverseKinematicPanel, 'numeric');
            app.PZEditFieldLabel = uilabel(app.InverseKinematicPanel);
            app.PZEditFieldLabel.Text = 'PZ';
            app.PZEditField = uieditfield(app.InverseKinematicPanel, 'numeric');
            app.ComputeButton = uibutton(app.InverseKinematicPanel, 'push');
            app.ComputeButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeButtonPushed, true);
            app.ComputeButton.Text = 'Compute';
            app.CurrentPosePanel = uipanel(app.UIFigure);
            app.CurrentPosePanel.Title = 'Current Pose';
            app.Label_7 = uilabel(app.CurrentPosePanel);
            app.Label_7.Text = '';
            app.Label_8 = uilabel(app.CurrentPosePanel);
            app.Label_8.Text = '';
            app.Label_9 = uilabel(app.CurrentPosePanel);
            app.Label_9.Text = '';
            app.PXLabel = uilabel(app.CurrentPosePanel);
            app.PXLabel.Text = 'PX';
            app.PYLabel = uilabel(app.CurrentPosePanel);
            app.PYLabel.Text = 'PY';
            app.PZLabel = uilabel(app.CurrentPosePanel);
            app.PZLabel.Text = 'PZ';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            % Assign app.ContextMenu
            app.Label_7.ContextMenu = app.ContextMenu;
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end